classdef Main_code_Scrambling_Dataset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BlindEstimationofScramblerPolynomialsLabel  matlab.ui.control.Label
        StandardsconsideredCCSDSDVBSDVBS2andV35Label  matlab.ui.control.Label
        ChooseFileandEstimateButton  matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        ResetButton                  matlab.ui.control.Button
        PolynomialEditFieldLabel     matlab.ui.control.Label
        PolynomialEditField          matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ESTIMATEButtonPushed(app, event)
       
        end

        % Button pushed function: ChooseFileandEstimateButton
        function ChooseFileandEstimateButtonPushed2(app, event)
tic
app.EditField.Value = 'Processing';
flag = 0;
flag1 = 0;
[Fname,Location] = uigetfile;
FileName = strcat(Location,Fname);
bits = importdata(FileName);
Tot_bits = length(bits);

[New_Iscr_Qscr,C_scrambled,frame_bits,frames,flag,final]=out_DVBS2(bits);
if (flag == 1)
    app.EditField.Value="DVBS2 is Used";
    app.PolynomialEditField.Value = '1+X^7+X^18, 1+Y^5+Y^7+Y^10+Y^18';
    flag1 = 1;
end

if (flag == 0)
    Tot_bits = length(bits);
    [flag,final,B,N,H_V]=out_CCSDS(bits,Tot_bits);
    if (flag == 1)
        app.EditField.Value="CCSDS is Used";
        app.PolynomialEditField.Value = '1+X^3+X^5+X^7+X^8';
    end
end

if (flag == 0) 
    [flag,final,B,N,H_V]=out_DVBS(bits,Tot_bits);
    if (flag == 1)
        app.EditField.Value="DVBS is Used";
        app.PolynomialEditField.Value = '1+X^14+X^15';
    end
end

if (flag == 0)
   flag = 1;
   app.EditField.Value="V.35 is Used";
   app.PolynomialEditField.Value = '1+x^-3+x^-20';
end

if (flag1 == 0)
    plot(-(H_V));
end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            close all;
            app.EditField.Value=" ";
            app.PolynomialEditField.Value = ' ';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BlindEstimationofScramblerPolynomialsLabel = uilabel(app.UIFigure);
            app.BlindEstimationofScramblerPolynomialsLabel.Text = 'Blind Estimation of Scrambler Polynomials';
            app.StandardsconsideredCCSDSDVBSDVBS2andV35Label = uilabel(app.UIFigure);
            app.StandardsconsideredCCSDSDVBSDVBS2andV35Label.Text = 'Standards considered: CCSDS, DVB-S, DVB-S2 and V.35';
            app.ChooseFileandEstimateButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileandEstimateButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileandEstimateButtonPushed2, true);
            app.ChooseFileandEstimateButton.Text = 'Choose File and Estimate';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = ' ';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PolynomialEditFieldLabel = uilabel(app.UIFigure);
            app.PolynomialEditFieldLabel.Text = 'Polynomial';
            app.PolynomialEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_code_Scrambling_Dataset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end