classdef Image_Processing_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RowEditFieldLabel            matlab.ui.control.Label
        RowEditField                 matlab.ui.control.NumericEditField
        ColEditFieldLabel            matlab.ui.control.Label
        ColEditField                 matlab.ui.control.NumericEditField
        InputTypeButtonGroup         matlab.ui.container.ButtonGroup
        ImageButton_2                matlab.ui.control.RadioButton
        ScrambledButton              matlab.ui.control.RadioButton
        NextButton                   matlab.ui.control.Button
        StandardsButtonGroup         matlab.ui.container.ButtonGroup
        CCSDSButton_2                matlab.ui.control.RadioButton
        DVBSButton_2                 matlab.ui.control.RadioButton
        DVBS2Button_2                matlab.ui.control.RadioButton
        NextButton_2                 matlab.ui.control.Button
        ImageTypeButtonGroup         matlab.ui.container.ButtonGroup
        ColorButton_2                matlab.ui.control.RadioButton
        BWButton_2                   matlab.ui.control.RadioButton
        NextButton_3                 matlab.ui.control.Button
        WebcamButton                 matlab.ui.control.RadioButton
        GetFileButton                matlab.ui.control.Button
        RUNButton                    matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        ModulationTypeisEditField    matlab.ui.control.EditField
        NextButton_4                 matlab.ui.control.Button
        ImageProcessingAppLabel      matlab.ui.control.Label
        StandardsconsideredCCSDSDVBSandDVBS2Label  matlab.ui.control.Label
        StandardUsedLabel            matlab.ui.control.Label
        ModulationTypeisEditField_2  matlab.ui.control.EditField
        PolynomialLabel              matlab.ui.control.Label
    end

properties (Access = private)
    JJ=0;
    II=0;
    KK=0;
    file='C:/';
    modtype=0;
end
    
methods (Access = private)
      
function [xxx,yyy,zzz] = func3(app)
clc;
if app.II == 1
     X =imread(app.file);
    if app.KK == 1
        [rows,columns,n] = size(X);
        [img_bits] = img_2_bits(X,rows,columns);
    elseif app.KK == 2
        BW = im2bw(X,0.3);
        [rows, columns] = size(BW);
        img_bits = reshape(BW,1,numel(BW));
    elseif app.KK == 3
        [rows,columns,n] = size(X);
        [img_bits] = img_2_bits(X,rows,columns);
    end
else
    bits =importdata(app.file);
    clc;
    rows = app.RowEditField.Value;
    columns = app.ColEditField.Value;
end

save('Image_bits','img_bits');
if app.II == 1
     Tot_bits = length(img_bits);
    if app.JJ == 1
        [bits] = CCSDS_img(img_bits);
    elseif app.JJ == 2
        [bits] = DVBS_img(img_bits);
    elseif app.JJ == 4
        [bits,z,frames,frame_bits,bits1] = DVBS2_img(img_bits);
    end 
end

save('Without_error','bits');
for x = 1:12:length(bits)
    bits(x) = xor(bits(x),1);
end
save('With_error','bits');
%%Descrambling Process Start
if app.II == 1 || app.II == 2
    [New_Iscr_Qscr,C_scrambled,frame_bits,frames,flag,final]=out_DVBS2(bits);
    if (flag == 1)
        app.modtype=4;
        Input_seq = New_Iscr_Qscr./C_scrambled;
        x1 = reshape(real(Input_seq),1,(frame_bits*frames));
        x2 = reshape(imag(Input_seq),1,(frame_bits*frames));
        dout = uint8([x1,x2]);
        bitsR = real(bits);
        bitsI = imag(bits);
        clear bits;
        bits = uint8([bitsR bitsI]);
    end 
    if (flag == 0)
        bits = (2*bits)-1;
        Tot_bits = length(bits);    
        [flag,final,B,N,H_V]=out_CCSDS(bits,Tot_bits);
    if (flag == 1) 
        app.modtype=1;
        bits = ((bits+1)/2);
        x = repmat(B,1,N+1);
        dout = xor(bits,x(1:length(bits)));
    end
    end
    if (flag == 0) 
        [flag,final,B,N,H_V]=out_DVBS(bits,Tot_bits);
        if (flag == 1)
            app.modtype=2;
            bits = ((bits+1)/2);
            x = repmat(B,1,N+1);
            dout = xor(bits,x(1:length(bits)));
        end
    end
end
save('Descrambled_bits','dout');

if app.KK == 1
    [image] = bits_2_img(dout,rows,columns);
    image = uint8(image);
    [imageScrambled] = bits_2_img(bits,rows,columns);
    imageScrambled = uint8(imageScrambled);
elseif app.KK == 2
    image = reshape(dout,rows,columns);
    imageScrambled = reshape(bits,rows,columns);
else if app.KK == 3
    [image] = bits_2_img(dout,rows,columns);
    image = uint8(image);
    [imageScrambled] = bits_2_img(bits,rows,columns);
    imageScrambled = uint8(imageScrambled);
end
end
if app.modtype == 4
    xxx=0;
else
    xxx=-H_V;
end
yyy=image;
zzz=imageScrambled;
end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.ImageButton_2.Value
                app.II=1;
                app.CCSDSButton_2.Enable='on';
                app.DVBSButton_2.Enable='on';
                app.DVBS2Button_2.Enable='on';
                app.NextButton_2.Enable='on';
            else
                app.II=2;
                app.ColorButton_2.Enable='on';
                app.BWButton_2.Enable='on';
                app.NextButton_3.Enable='on';
            end
            app.ImageButton_2.Enable='off';
            app.ScrambledButton.Enable='off';
            app.NextButton.Enable='off';
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            if app.CCSDSButton_2.Value
                app.JJ=1;
            elseif app.DVBSButton_2.Value
                app.JJ=2;
            elseif app.DVBS2Button_2.Value
                app.JJ=4;
            end
            app.CCSDSButton_2.Enable='off';
            app.DVBSButton_2.Enable='off';
            app.DVBS2Button_2.Enable='off';
            app.ColorButton_2.Enable='on';
            if app.JJ~=4
            app.BWButton_2.Enable='on';
            end
            app.NextButton_3.Enable='on';
            app.NextButton_2.Enable='off';
            app.WebcamButton.Enable='on';
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            if app.ColorButton_2.Value
                app.KK=1;
            elseif app.BWButton_2.Value
                app.KK=2;
            elseif app.WebcamButton.Value
                app.KK=3;
            end
            app.GetFileButton.Enable='on';
            app.ColorButton_2.Enable='off';
            app.BWButton_2.Enable='off';
            app.NextButton_3.Enable='off';
            app.WebcamButton.Enable='off';
        end

        % Button pushed function: GetFileButton
        function GetFileButtonPushed(app, event)
            if app.KK==3
                camm=webcam;
                imggg = snapshot(camm);
                imwrite(imggg,'snap.png');
                app.file='snap.png';
                clear camm;
            else
                [ff, loc]=uigetfile();
               app.file=strcat(loc,ff); 
            end
            app.GetFileButton.Enable='off';
            if app.II==1
                app.RUNButton.Enable='on';
            elseif app.II==2
                app.RowEditField.Enable='on';
                app.NextButton_4.Enable = 'on';
                app.ColEditField.Enable='on';
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
app.RUNButton.Enable='off';           
            [xxx,yyy,zzz]=func3(app);

if app.II==2
    app.ModulationTypeisEditField.Visible='on';
end
if app.modtype==1
    app.ModulationTypeisEditField.Value='CCSDS';
    app.ModulationTypeisEditField_2.Value='1+X^3+X^5+X^7+X^8';
elseif app.modtype==2
    app.ModulationTypeisEditField.Value='DVBS';
    app.ModulationTypeisEditField_2.Value='1+X^14+X^15';
elseif app.modtype==4
    app.ModulationTypeisEditField.Value='DVBS2';
    app.ModulationTypeisEditField_2.Value='1+X^7+X^18, 1+Y^5+Y^7+Y^10+Y^18';
end
if app.modtype==4 && app.II==1
    objImg=imread(app.file);
    subplot(1,3,3),imshow(yyy);
    title('Descrambled image');
    subplot(1,3,2),imshow(zzz);
    title('Scrambled output');
    subplot(1,3,1),imshow(objImg);
    title('Input Image');
elseif app.II==2
    subplot(2,2,[1,2]),plot(xxx);
    title('Polynomial');
    subplot(2,2,4),imshow(yyy);
    title('Descrambled image');
    subplot(2,2,3),imshow(zzz);
    title('Scrambled output');
elseif app.modtype~=4 && app.II==1
     objImg=imread(app.file);
     subplot(2,3,[1,2,3]),plot(xxx);
    title('Polynomial');
    subplot(2,3,6),imshow(yyy);
    title('Descrambled image');
    subplot(2,3,5),imshow(zzz);
    title('Scrambled output');
    subplot(2,3,4),imshow(objImg);
    title('Input Image');
end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            close all;
            app.RowEditField.Enable='off';
            app.ColEditField.Enable='off';
            app.ImageButton_2.Enable='on';
            app.ScrambledButton.Enable='on';
            app.NextButton.Enable='on';
            app.CCSDSButton_2.Enable='off';
            app.DVBSButton_2.Enable='off';
            app.DVBS2Button_2.Enable='off';
            app.NextButton_2.Enable='off';
            app.ColorButton_2.Enable='off';
            app.BWButton_2.Enable='off';
            app.NextButton_3.Enable='off';
            app.GetFileButton.Enable='off';
            app.ModulationTypeisEditField.Value=' ';
            app.ModulationTypeisEditField_2.Value=' ';
            app.RowEditField.Value=0;
            app.ColEditField.Value=0;
            app.RUNButton.Enable='off';
            app.NextButton_4.Enable='off';
        end

        % Button pushed function: NextButton_4
        function NextButton_4Pushed(app, event)
            app.RowEditField.Enable='off';
            app.ColEditField.Enable='off';
            app.NextButton_4.Enable = 'off';
           app.RUNButton.Enable='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RowEditFieldLabel = uilabel(app.UIFigure);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.UIFigure, 'numeric');
            app.RowEditField.Enable = 'off';
            app.ColEditFieldLabel = uilabel(app.UIFigure);
            app.ColEditFieldLabel.Text = 'Col';
            app.ColEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColEditField.Enable = 'off';
            app.InputTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.InputTypeButtonGroup.Title = 'Input Type';
            app.ImageButton_2 = uiradiobutton(app.InputTypeButtonGroup);
            app.ImageButton_2.Text = 'Image';
            app.ImageButton_2.Value = true;
            app.ScrambledButton = uiradiobutton(app.InputTypeButtonGroup);
            app.ScrambledButton.Text = 'Scrambled';
            app.NextButton = uibutton(app.InputTypeButtonGroup, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.StandardsButtonGroup = uibuttongroup(app.UIFigure);
            app.StandardsButtonGroup.Title = 'Standards';
            app.CCSDSButton_2 = uiradiobutton(app.StandardsButtonGroup);
            app.CCSDSButton_2.Enable = 'off';
            app.CCSDSButton_2.Text = 'CCSDS';
            app.CCSDSButton_2.Value = true;
            app.DVBSButton_2 = uiradiobutton(app.StandardsButtonGroup);
            app.DVBSButton_2.Enable = 'off';
            app.DVBSButton_2.Text = 'DVBS';
            app.DVBS2Button_2 = uiradiobutton(app.StandardsButtonGroup);
            app.DVBS2Button_2.Enable = 'off';
            app.DVBS2Button_2.Text = 'DVBS2';
            app.NextButton_2 = uibutton(app.StandardsButtonGroup, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Enable = 'off';
            app.NextButton_2.Text = 'Next';
            app.ImageTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageTypeButtonGroup.Title = 'Image Type';
            app.ColorButton_2 = uiradiobutton(app.ImageTypeButtonGroup);
            app.ColorButton_2.Enable = 'off';
            app.ColorButton_2.Text = 'Color';
            app.ColorButton_2.Value = true;
            app.BWButton_2 = uiradiobutton(app.ImageTypeButtonGroup);
            app.BWButton_2.Enable = 'off';
            app.BWButton_2.Text = 'B&W';
            app.NextButton_3 = uibutton(app.ImageTypeButtonGroup, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Enable = 'off';
            app.NextButton_3.Text = 'Next';
            app.WebcamButton = uiradiobutton(app.ImageTypeButtonGroup);
            app.WebcamButton.Enable = 'off';
            app.WebcamButton.Text = 'Webcam';
            app.GetFileButton = uibutton(app.UIFigure, 'push');
            app.GetFileButton.ButtonPushedFcn = createCallbackFcn(app, @GetFileButtonPushed, true);
            app.GetFileButton.Enable = 'off';
            app.GetFileButton.Text = 'Get File';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = 'RUN';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ModulationTypeisEditField = uieditfield(app.UIFigure, 'text');
            app.ModulationTypeisEditField.Editable = 'off';
            app.NextButton_4 = uibutton(app.UIFigure, 'push');
            app.NextButton_4.ButtonPushedFcn = createCallbackFcn(app, @NextButton_4Pushed, true);
            app.NextButton_4.Enable = 'off';
            app.NextButton_4.Text = 'Next';
            app.ImageProcessingAppLabel = uilabel(app.UIFigure);
            app.ImageProcessingAppLabel.Text = 'Image Processing App';
            app.StandardsconsideredCCSDSDVBSandDVBS2Label = uilabel(app.UIFigure);
            app.StandardsconsideredCCSDSDVBSandDVBS2Label.Text = 'Standards considered: CCSDS, DVB-S and DVB-S2.';
            app.StandardUsedLabel = uilabel(app.UIFigure);
            app.StandardUsedLabel.Text = 'Standard Used :';
            app.ModulationTypeisEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ModulationTypeisEditField_2.Editable = 'off';
            app.PolynomialLabel = uilabel(app.UIFigure);
            app.PolynomialLabel.Text = 'Polynomial :';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Processing_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end