classdef Polynomial_AnyScrambler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        BlindScramblingCodeIdentificationSynchronousLabel  matlab.ui.control.Label
        SelectOneOptionButtonGroup      matlab.ui.container.ButtonGroup
        ScramblingButton                matlab.ui.control.RadioButton
        DescramblingButton              matlab.ui.control.RadioButton
        NextButton                      matlab.ui.control.Button
        SelectPolynomialLabel           matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        X1SwitchLabel                   matlab.ui.control.Label
        X1Switch                        matlab.ui.control.Switch
        X2SwitchLabel                   matlab.ui.control.Label
        X2Switch                        matlab.ui.control.Switch
        X3SwitchLabel                   matlab.ui.control.Label
        X3Switch                        matlab.ui.control.Switch
        X4SwitchLabel                   matlab.ui.control.Label
        X4Switch                        matlab.ui.control.Switch
        X5SwitchLabel                   matlab.ui.control.Label
        X5Switch                        matlab.ui.control.Switch
        X6SwitchLabel                   matlab.ui.control.Label
        X6Switch                        matlab.ui.control.Switch
        X7SwitchLabel                   matlab.ui.control.Label
        X7Switch                        matlab.ui.control.Switch
        X8SwitchLabel                   matlab.ui.control.Label
        X8Switch                        matlab.ui.control.Switch
        X9SwitchLabel                   matlab.ui.control.Label
        X9Switch                        matlab.ui.control.Switch
        X10SwitchLabel                  matlab.ui.control.Label
        X10Switch                       matlab.ui.control.Switch
        X11SwitchLabel                  matlab.ui.control.Label
        X11Switch                       matlab.ui.control.Switch
        X12SwitchLabel                  matlab.ui.control.Label
        X12Switch                       matlab.ui.control.Switch
        X13SwitchLabel                  matlab.ui.control.Label
        X13Switch                       matlab.ui.control.Switch
        X14SwitchLabel                  matlab.ui.control.Label
        X14Switch                       matlab.ui.control.Switch
        X15SwitchLabel                  matlab.ui.control.Label
        X15Switch                       matlab.ui.control.Switch
        ResetButton                     matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        ScrambleddatafilenameEditFieldLabel  matlab.ui.control.Label
        ScrambleddatafilenameEditField  matlab.ui.control.EditField
        InputdatafilenameEditFieldLabel  matlab.ui.control.Label
        InputdatafilenameEditField      matlab.ui.control.EditField
        GetFileandRunButton_2           matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Tab2                            matlab.ui.container.Tab
        DescramblingLabel               matlab.ui.control.Label
        SelectOneOptionButtonGroup_2    matlab.ui.container.ButtonGroup
        StoreDescrambledDataButton      matlab.ui.control.RadioButton
        OnlyshowpolynomialButton        matlab.ui.control.RadioButton
        NextButton_2                    matlab.ui.control.Button
        GetFileandRunButton             matlab.ui.control.Button
        PolynomialEditFieldLabel        matlab.ui.control.Label
        PolynomialEditField             matlab.ui.control.EditField
        ResetButton_2                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        OutputfilenameEditFieldLabel    matlab.ui.control.Label
        OutputfilenameEditField         matlab.ui.control.EditField
        mEditFieldLabel                 matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.ScramblingButton.Value
                app.SelectPolynomialLabel.Enable = 'on';
                app.X1Switch.Enable = 'on';
                app.X2Switch.Enable = 'on';
                app.X3Switch.Enable = 'on';
                app.X4Switch.Enable = 'on';
                app.X5Switch.Enable = 'on';
                app.X6Switch.Enable = 'on';
                app.X7Switch.Enable = 'on';
                app.X8Switch.Enable = 'on';
                app.X9Switch.Enable = 'on';
                app.X10Switch.Enable = 'on';
                app.X11Switch.Enable = 'on';
                app.X12Switch.Enable = 'on';
                app.X13Switch.Enable = 'on';
                app.X14Switch.Enable = 'on';
                app.X15Switch.Enable = 'on';
                app.RunButton.Enable = 'on';
                app.GetFileandRunButton_2.Enable = 'on';
            elseif app.DescramblingButton.Value
               app.TabGroup.SelectedTab = app.Tab2;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            val1 = app.X1Switch.Value;
            val2 = app.X2Switch.Value;
            val3 = app.X3Switch.Value;
            val4 = app.X4Switch.Value;
            val5 = app.X5Switch.Value;
            val6 = app.X6Switch.Value;
            val7 = app.X7Switch.Value;
            val8 = app.X8Switch.Value;
            val9 = app.X9Switch.Value;
            val10 = app.X10Switch.Value;
            val11 = app.X11Switch.Value;
            val12 = app.X12Switch.Value;
            val13 = app.X13Switch.Value;
            val14 = app.X14Switch.Value;
            val15 = app.X15Switch.Value;
            ii=16;            
            tempPoly = [1==1 val1=='1' val2=='1' val3=='1' val4=='1' val5=='1' val6=='1' val7=='1' val8=='1' val9=='1' val10=='1' val11=='1' val12=='1' val13=='1' val14=='1' val15=='1'];
            while tempPoly(ii)==0
                ii=ii-1;
            end
            poly=tempPoly(1:ii);
            filename = strcat(app.ScrambleddatafilenameEditField.Value,'.mat');
            ipfile = strcat(app.InputdatafilenameEditField.Value,'.mat');
            
            e = 0.1;
            m = length(poly)-1;
            L = (2^m)-1;
            N=100;
            Tot_bits = L*N;
            num_z = ceil((Tot_bits)*((1/2)+e));
            a = ones(1,Tot_bits);
            a(1,1:num_z) = 0;
            randbits = reshape(a(randperm(Tot_bits)),1,Tot_bits);            
            
            [bitsequence m] = Scrambling(poly,ipfile,randbits);
            save(filename,'bitsequence');
            app.EditField.Value = 'Data Stored';
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            if app.StoreDescrambledDataButton.Value    
                app.GetFileandRunButton.Enable = 'on';
                app.PolynomialEditField.Enable = 'on';
                app.OutputfilenameEditField.Enable = 'on';
                app.mEditField.Enable = 'on';
            else
                app.GetFileandRunButton.Enable = 'on';
                app.PolynomialEditField.Enable = 'on';
                app.mEditField.Enable = 'on';
            end
        end

        % Button pushed function: GetFileandRunButton
        function GetFileandRunButtonPushed(app, event)
            [f_name, loc]=uigetfile();
            file=strcat(loc,f_name);
            load(file);
            m = app.mEditField.Value;
            [polyOut,H_V]=findpoly(bitsequence,m);
            val = bi2de(polyOut);
            if (val == 169)
                app.PolynomialEditField.Value = 'CCSDS is Used';
                plot(app.UIAxes,H_V);
            elseif (val == 16385)
                app.PolynomialEditField.Value = 'DVBS is Used';
                plot(app.UIAxes,H_V);
            else
                st=("1");
                poly1=polyOut(2:end);
                for l=1:length(poly1)
                    if(poly1(l)==1)
                        st=strcat(st," + x^");
                        if l>9
                            st=strcat(st,'1');
                            st=strcat(st,char(l+38));
                        else
                            st=strcat(st,char(l+48));
                        end
                        
                    end 
                end
                if (st == '1')
                    app.PolynomialEditField.Value = 'Given data is Unscrambled';
                    plot(app.UIAxes,H_V);
                else
                    app.PolynomialEditField.Value=st;
                    plot(app.UIAxes,H_V);
                    if app.StoreDescrambledDataButton.Value==1
                        data=deScramb(bitsequence,polyOut);
                        data=double(data);
                        fil=strcat(app.OutputfilenameEditField.Value,'.mat');
                        save(fil,'data');
                    end
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
%             app.TabGroup.SelectedTab = app.Tab;
            app.X1Switch.Value='0';
            app.X2Switch.Value='0';
            app.X3Switch.Value='0';
            app.X4Switch.Value='0';
            app.X5Switch.Value='0';
            app.X6Switch.Value='0';
            app.X7Switch.Value='0';
            app.X8Switch.Value='0';
            app.X9Switch.Value='0';
            app.X10Switch.Value='0';
            app.X11Switch.Value='0';
            app.X12Switch.Value='0';
            app.X13Switch.Value='0';
            app.X14Switch.Value='0';
            app.X15Switch.Value='0';
            app.mEditField.Value = '0';
            app.EditField.Value = ' ';
            app.PolynomialEditField.Value = ' ';
            app.SelectPolynomialLabel.Enable = 'off';
            app.X1Switch.Enable = 'off';
            app.X2Switch.Enable = 'off';
            app.X3Switch.Enable = 'off';
            app.X4Switch.Enable = 'off';
            app.X5Switch.Enable = 'off';
            app.X6Switch.Enable = 'off';
            app.X7Switch.Enable = 'off';
            app.X8Switch.Enable = 'off';
            app.X9Switch.Enable = 'off';
            app.X10Switch.Enable = 'off';
            app.X11Switch.Enable = 'off';
            app.X12Switch.Enable = 'off';
            app.X13Switch.Enable = 'off';
            app.X14Switch.Enable = 'off';
            app.X15Switch.Enable = 'off';
            app.RunButton.Enable = 'off';
            app.mEditField.Enable = 'off';
            app.GetFileandRunButton.Enable = 'off';
            app.PolynomialEditField.Enable = 'off';
            app.GetFileandRunButton_2.Enable = 'off';
            plot(app.UIAxes,0)
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
             app.X1Switch.Value='0';
            app.X2Switch.Value='0';
            app.X3Switch.Value='0';
            app.X4Switch.Value='0';
            app.X5Switch.Value='0';
            app.X6Switch.Value='0';
            app.X7Switch.Value='0';
            app.X8Switch.Value='0';
            app.X9Switch.Value='0';
            app.X10Switch.Value='0';
            app.X11Switch.Value='0';
            app.X12Switch.Value='0';
            app.X13Switch.Value='0';
            app.X14Switch.Value='0';
            app.X15Switch.Value='0';
            app.mEditField.Value = '0';
            app.EditField.Value = ' ';
            app.SelectPolynomialLabel.Enable = 'off';
            app.X1Switch.Enable = 'off';
            app.X2Switch.Enable = 'off';
            app.X3Switch.Enable = 'off';
            app.X4Switch.Enable = 'off';
            app.X5Switch.Enable = 'off';
            app.X6Switch.Enable = 'off';
            app.X7Switch.Enable = 'off';
            app.X8Switch.Enable = 'off';
            app.X9Switch.Enable = 'off';
            app.X10Switch.Enable = 'off';
            app.X11Switch.Enable = 'off';
            app.X12Switch.Enable = 'off';
            app.X13Switch.Enable = 'off';
            app.X14Switch.Enable = 'off';
            app.X15Switch.Enable = 'off';
            app.RunButton.Enable = 'off';
            app.mEditField.Enable = 'off';
            app.GetFileandRunButton.Enable = 'off';
            app.PolynomialEditField.Enable = 'off';
            app.PolynomialEditField.Value = '';
            app.OutputfilenameEditField.Enable = 'off';
            app.GetFileandRunButton_2.Enable = 'off';
            plot(app.UIAxes,0)
        end

        % Button pushed function: GetFileandRunButton_2
        function GetFileandRunButton_2Pushed(app, event)
            val1 = app.X1Switch.Value;
            val2 = app.X2Switch.Value;
            val3 = app.X3Switch.Value;
            val4 = app.X4Switch.Value;
            val5 = app.X5Switch.Value;
            val6 = app.X6Switch.Value;
            val7 = app.X7Switch.Value;
            val8 = app.X8Switch.Value;
            val9 = app.X9Switch.Value;
            val10 = app.X10Switch.Value;
            val11 = app.X11Switch.Value;
            val12 = app.X12Switch.Value;
            val13 = app.X13Switch.Value;
            val14 = app.X14Switch.Value;
            val15 = app.X15Switch.Value;
            ii=16;            
            tempPoly = [1==1 val1=='1' val2=='1' val3=='1' val4=='1' val5=='1' val6=='1' val7=='1' val8=='1' val9=='1' val10=='1' val11=='1' val12=='1' val13=='1' val14=='1' val15=='1'];
            while tempPoly(ii)==0
                ii=ii-1;
            end
            poly=tempPoly(1:ii);
            filename = strcat(app.ScrambleddatafilenameEditField.Value,'.mat');
            ipfile = strcat(app.InputdatafilenameEditField.Value,'.mat');
            
            [f_name, loc]=uigetfile();
            file=strcat(loc,f_name);
            load(file,'ipdata');   
            
            [bitsequence m] = Scrambling(poly,ipfile,ipdata);
            save(filename,'bitsequence','m');
            app.EditField.Value = 'Data Stored';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.BlindScramblingCodeIdentificationSynchronousLabel = uilabel(app.Tab);
            app.BlindScramblingCodeIdentificationSynchronousLabel.Text = 'Blind Scrambling Code Identification (Synchronous)';
            app.SelectOneOptionButtonGroup = uibuttongroup(app.Tab);
            app.SelectOneOptionButtonGroup.Title = 'Select One Option';
            app.ScramblingButton = uiradiobutton(app.SelectOneOptionButtonGroup);
            app.ScramblingButton.Text = 'Scrambling';
            app.ScramblingButton.Value = true;
            app.DescramblingButton = uiradiobutton(app.SelectOneOptionButtonGroup);
            app.DescramblingButton.Text = 'Descrambling';
            app.NextButton = uibutton(app.SelectOneOptionButtonGroup, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SelectPolynomialLabel = uilabel(app.Tab);
            app.SelectPolynomialLabel.Enable = 'off';
            app.SelectPolynomialLabel.Text = 'Select Polynomial';
            app.RunButton = uibutton(app.Tab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.X1SwitchLabel = uilabel(app.Tab);
            app.X1SwitchLabel.Text = 'X^1';
            app.X1Switch = uiswitch(app.Tab, 'slider');
            app.X1Switch.Items = {'0', '1'};
            app.X1Switch.Enable = 'off';
            app.X1Switch.Value = '0';
            app.X2SwitchLabel = uilabel(app.Tab);
            app.X2SwitchLabel.Text = {'X^2'; ''};
            app.X2Switch = uiswitch(app.Tab, 'slider');
            app.X2Switch.Items = {'0', '1'};
            app.X2Switch.Enable = 'off';
            app.X2Switch.Value = '0';
            app.X3SwitchLabel = uilabel(app.Tab);
            app.X3SwitchLabel.Text = 'X^3';
            app.X3Switch = uiswitch(app.Tab, 'slider');
            app.X3Switch.Items = {'0', '1'};
            app.X3Switch.Enable = 'off';
            app.X3Switch.Value = '0';
            app.X4SwitchLabel = uilabel(app.Tab);
            app.X4SwitchLabel.Text = 'X^4';
            app.X4Switch = uiswitch(app.Tab, 'slider');
            app.X4Switch.Items = {'0', '1'};
            app.X4Switch.Enable = 'off';
            app.X4Switch.Value = '0';
            app.X5SwitchLabel = uilabel(app.Tab);
            app.X5SwitchLabel.Text = 'X^5';
            app.X5Switch = uiswitch(app.Tab, 'slider');
            app.X5Switch.Items = {'0', '1'};
            app.X5Switch.Enable = 'off';
            app.X5Switch.Value = '0';
            app.X6SwitchLabel = uilabel(app.Tab);
            app.X6SwitchLabel.Text = {'X^6'; ''};
            app.X6Switch = uiswitch(app.Tab, 'slider');
            app.X6Switch.Items = {'0', '1'};
            app.X6Switch.Enable = 'off';
            app.X6Switch.Value = '0';
            app.X7SwitchLabel = uilabel(app.Tab);
            app.X7SwitchLabel.Text = {'X^7'; ''; ''};
            app.X7Switch = uiswitch(app.Tab, 'slider');
            app.X7Switch.Items = {'0', '1'};
            app.X7Switch.Enable = 'off';
            app.X7Switch.Value = '0';
            app.X8SwitchLabel = uilabel(app.Tab);
            app.X8SwitchLabel.Text = 'X^8';
            app.X8Switch = uiswitch(app.Tab, 'slider');
            app.X8Switch.Items = {'0', '1'};
            app.X8Switch.Enable = 'off';
            app.X8Switch.Value = '0';
            app.X9SwitchLabel = uilabel(app.Tab);
            app.X9SwitchLabel.Text = 'X^9';
            app.X9Switch = uiswitch(app.Tab, 'slider');
            app.X9Switch.Items = {'0', '1'};
            app.X9Switch.Enable = 'off';
            app.X9Switch.Value = '0';
            app.X10SwitchLabel = uilabel(app.Tab);
            app.X10SwitchLabel.Text = 'X^10';
            app.X10Switch = uiswitch(app.Tab, 'slider');
            app.X10Switch.Items = {'0', '1'};
            app.X10Switch.Enable = 'off';
            app.X10Switch.Value = '0';
            app.X11SwitchLabel = uilabel(app.Tab);
            app.X11SwitchLabel.Text = 'X^11';
            app.X11Switch = uiswitch(app.Tab, 'slider');
            app.X11Switch.Items = {'0', '1'};
            app.X11Switch.Enable = 'off';
            app.X11Switch.Value = '0';
            app.X12SwitchLabel = uilabel(app.Tab);
            app.X12SwitchLabel.Text = {'X^12'; ''};
            app.X12Switch = uiswitch(app.Tab, 'slider');
            app.X12Switch.Items = {'0', '1'};
            app.X12Switch.Enable = 'off';
            app.X12Switch.Value = '0';
            app.X13SwitchLabel = uilabel(app.Tab);
            app.X13SwitchLabel.Text = 'X^13';
            app.X13Switch = uiswitch(app.Tab, 'slider');
            app.X13Switch.Items = {'0', '1'};
            app.X13Switch.Enable = 'off';
            app.X13Switch.Value = '0';
            app.X14SwitchLabel = uilabel(app.Tab);
            app.X14SwitchLabel.Text = 'X^14';
            app.X14Switch = uiswitch(app.Tab, 'slider');
            app.X14Switch.Items = {'0', '1'};
            app.X14Switch.Enable = 'off';
            app.X14Switch.Value = '0';
            app.X15SwitchLabel = uilabel(app.Tab);
            app.X15SwitchLabel.Text = 'X^15';
            app.X15Switch = uiswitch(app.Tab, 'slider');
            app.X15Switch.Items = {'0', '1'};
            app.X15Switch.Enable = 'off';
            app.X15Switch.Value = '0';
            app.ResetButton = uibutton(app.Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EditField = uieditfield(app.Tab, 'text');
            app.ScrambleddatafilenameEditFieldLabel = uilabel(app.Tab);
            app.ScrambleddatafilenameEditFieldLabel.Text = 'Scrambled data file name';
            app.ScrambleddatafilenameEditField = uieditfield(app.Tab, 'text');
            app.ScrambleddatafilenameEditField.Value = 'Scrambled_Data';
            app.InputdatafilenameEditFieldLabel = uilabel(app.Tab);
            app.InputdatafilenameEditFieldLabel.Text = 'Input data file name';
            app.InputdatafilenameEditField = uieditfield(app.Tab, 'text');
            app.InputdatafilenameEditField.Value = 'Input_Sequence';
            app.GetFileandRunButton_2 = uibutton(app.Tab, 'push');
            app.GetFileandRunButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetFileandRunButton_2Pushed, true);
            app.GetFileandRunButton_2.Enable = 'off';
            app.GetFileandRunButton_2.Text = 'Get File and Run';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = 'crop.jpg';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.DescramblingLabel = uilabel(app.Tab2);
            app.DescramblingLabel.Text = 'Descrambling';
            app.SelectOneOptionButtonGroup_2 = uibuttongroup(app.Tab2);
            app.SelectOneOptionButtonGroup_2.Title = 'Select One Option';
            app.StoreDescrambledDataButton = uiradiobutton(app.SelectOneOptionButtonGroup_2);
            app.StoreDescrambledDataButton.Text = 'Store Descrambled Data';
            app.StoreDescrambledDataButton.Value = true;
            app.OnlyshowpolynomialButton = uiradiobutton(app.SelectOneOptionButtonGroup_2);
            app.OnlyshowpolynomialButton.Text = 'Only show polynomial';
            app.NextButton_2 = uibutton(app.SelectOneOptionButtonGroup_2, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.GetFileandRunButton = uibutton(app.Tab2, 'push');
            app.GetFileandRunButton.ButtonPushedFcn = createCallbackFcn(app, @GetFileandRunButtonPushed, true);
            app.GetFileandRunButton.Enable = 'off';
            app.GetFileandRunButton.Text = 'Get File and Run';
            app.PolynomialEditFieldLabel = uilabel(app.Tab2);
            app.PolynomialEditFieldLabel.Text = 'Polynomial';
            app.PolynomialEditField = uieditfield(app.Tab2, 'text');
            app.PolynomialEditField.Editable = 'off';
            app.PolynomialEditField.Enable = 'off';
            app.ResetButton_2 = uibutton(app.Tab2, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.UIAxes = uiaxes(app.Tab2);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.OutputfilenameEditFieldLabel = uilabel(app.Tab2);
            app.OutputfilenameEditFieldLabel.Text = 'Output file name';
            app.OutputfilenameEditField = uieditfield(app.Tab2, 'text');
            app.OutputfilenameEditField.Enable = 'off';
            app.OutputfilenameEditField.Value = 'Descrambled_Data';
            app.mEditFieldLabel = uilabel(app.Tab2);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.Tab2, 'numeric');
            app.mEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Polynomial_AnyScrambler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end